classdef Stumblemeter_matlab_script < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ImportCalculateButton  matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        StumblemeterLabel      matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        HelpButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportCalculateButton
        function ImportCalculateButtonPushed(app, event)
cla(app.UIAxes) 
app.TextArea.Value = 'Calculating...'
        
[file,path,indx] = uigetfile
filename = [path file];

% Pre-processing
rawData = CWA_readFile(filename);
data = array2table(rawData.AXES);
rawData2=rawData.AXES;

window_length=256;

y1Data = rawData2(:,2);
y2Data = rawData2(:,3);
y3Data = rawData2(:,4);
y4Data = rawData2(:,5);
y5Data = rawData2(:,6);
y6Data = rawData2(:,7);
y7Data= abs(sqrt(y1Data.^2+y2Data.^2+y3Data.^2)-1);
y8Data= abs(sqrt(y4Data.^2+y5Data.^2+y6Data.^2));

windows=floor(length(y1Data)/window_length);
windows_overlap = windows*2-1;

clear windows

% Event-defined window segmentation
y3Data_peaks=y3Data*-1;

[pks,loc]=findpeaks(y3Data_peaks,'MinPeakDistance',400,'MinPeakHeight',1.75);

windows=numel(pks);

start_window=loc-127;
end_window=loc+128;

time_windows = datetime(data.Var1(loc),'ConvertFrom','datenum','Format','dd-MMM-yyyy HH:mm:ss');

% windows
for i=1:windows
y1Data_window(i,:)=y1Data(start_window(i):end_window(i))';
y2Data_window(i,:)=y2Data(start_window(i):end_window(i))';
y3Data_window(i,:)=y3Data(start_window(i):end_window(i))';
y4Data_window(i,:)=y4Data(start_window(i):end_window(i))';
y5Data_window(i,:)=y5Data(start_window(i):end_window(i))';
y6Data_window(i,:)=y6Data(start_window(i):end_window(i))';
y7Data_window(i,:)=y7Data(start_window(i):end_window(i))';
y8Data_window(i,:)=y8Data(start_window(i):end_window(i))';
end

% Fast Fourier Transform windows
for f=1:windows
    y1Data_window_fft(f,:) = fft(y1Data_window(f,:));
    y2Data_window_fft(f,:) = fft(y2Data_window(f,:));
    y3Data_window_fft(f,:) = fft(y3Data_window(f,:));
    y4Data_window_fft(f,:) = fft(y4Data_window(f,:));
    y5Data_window_fft(f,:) = fft(y5Data_window(f,:));
    y6Data_window_fft(f,:) = fft(y6Data_window(f,:));
    y7Data_window_fft(f,:) = fft(y7Data_window(f,:));
    y8Data_window_fft(f,:) = fft(y8Data_window(f,:));
end

% raw sensor data
rawSensorDataTrain = table(y1Data_window, y2Data_window, y3Data_window, y4Data_window, y5Data_window, y6Data_window, y7Data_window, y8Data_window);
rawSensorDataTrain_fft = table(y1Data_window_fft, y2Data_window_fft, y3Data_window_fft, y4Data_window_fft, y5Data_window_fft, y6Data_window_fft, y7Data_window_fft, y8Data_window_fft);

% Features
[h w]=size(rawSensorDataTrain);
for i=1:h
    for j=1:w
T_cov_temp=table2array(rawSensorDataTrain(i,j));
T_cov(i,j)=cov(T_cov_temp);
T_pentropy(i,j) = pentropy(T_cov_temp,6,'Instantaneous',false);
    end
end
clear T_cov_temp

for i= 1:8
X=table2array(rawSensorDataTrain(:,i));
T_cummax(:,i)=sum(cummax(X'))';
T_cummin(:,i)=sum(cummin(X'))';
T_cum=T_cummax+T_cummin;
T_iqr(:,i) = iqr(X,2);
T_kurtosis(:,i) = kurtosis(X,1,2);
T_mean(:,i) = mean(X,2);
T_median(:,i) = rms(X,2);
T_mad(:,i) = mad(X,0,2);
T_max(:,i) = max(X,[],2);
T_min(:,i) = min(X,[],2);
[~,T_pca(:,i)] = pca(X,'NumComponents',1);
T_peak2rms(:,i)=peak2rms(X')';

[Y_min,P_min]           = islocalmin(X,2);
[Y_max,P_max]           = islocalmax(X,2);
T_prominence(:,i)       = sum(P_min,2)+sum(P_max,2);
T_prominence_max(:,i)   = sum(P_max,2);
T_prominence_min(:,i)   = sum(P_min,2);

[Y_min_th,P_min_th]     = islocalmin(X,2,"MinProminence",500);  %kan weg?
[Y_max_th,P_max_th]     = islocalmax(X,2,"MinProminence",500);  % kan weg?
T_prominence_th(:,i)    = sum(P_min_th,2)+sum(P_max_th,2);     % kan weg?

T_rms(:,i)              = rms(X,2);
T_rssq(:,i)             = rssq(X,2);
T_range(:,i)            = range(X,2);
T_skewness(:,i)         = skewness(X,1,2);
T_stdv(:,i)             = std(X,[],2);
T_var(:,i)              = var(X')';
end

for i= 1:3
X2=table2array(rawSensorDataTrain(:,i));
 [Y_min_local,P_min_local] = islocalmin(X2,2,"MinProminence",50);
 [Y_max_local,P_max_local] = islocalmax(X2,2,"MinProminence",0.8);
T_sumlocal(:,i)    = sum(Y_min_local,2)+sum(Y_max_local,2);
end

for i= 4:6
X3=table2array(rawSensorDataTrain(:,i));
[Y_min_gyr,P_min_gyr] = islocalmin(X3,2,"MinProminence",1); 
[Y_max_gyr,P_max_gyr] = islocalmax(X3,2,"MinProminence",1); 
T_sumlocal_gyr(:,i-3) = sum(Y_min_gyr,2)+sum(Y_max_gyr,2);
end

for i=1:8
X=table2array(rawSensorDataTrain_fft(:,i));

N=256;
sqrtPyy = ((sqrt(abs(X).*abs(X)) * 2 )/N);

%Normalization
d=sqrtPyy(:,:);
d=d./sum(d'+ 1e-12)';

%Entropy Calculation
logd = log2(d + 1e-12);
T_entropy(:,i) = -sum([d.*logd]')/log2(length(d(1,:)));
end

% Scale data
X=[T_cov T_cum T_cummax T_cummin T_iqr T_kurtosis T_mean T_median T_mad T_max T_min T_entropy T_pca T_peak2rms T_pentropy T_prominence T_prominence_min T_prominence_max T_prominence_th T_rms T_rssq T_range T_stdv T_skewness T_sumlocal T_sumlocal_gyr T_var];
colmax = [1.54315705824551,1.14228376514334,1.43816062996809,24963.5935407477,46522.5702086473,11198.9789114950,1.47087055471730,22621.4651951514,-81.3132470000004,290.259718000000,612.527602000000,60306.5413790000,57313.9896850001,29031.8279980000,1662.33435961503,144406.127591025,616.211520000000,1131.92450500000,1213.88843600000,127996.086016000,90541.4545440000,59029.8117080000,1660.42530273882,132577.458479236,-169.487297000000,30.8546849999999,-253.586882000000,-4512.23731100000,-4977.21855800000,-715.316626000001];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
colmin = [0.00340946108395196,0.00405319024414994,0.0156483964158056,46.0048138438279,104.181012347829,28.1598153757779,0.00887691097023474,127.650717230433,-1415.27504100000,-584.915549000001,-979.374484000000,-92093.2267060001,-76503.9144350001,-37503.1443700000,141.900277239386,10936.8155196428,-171.899156000000,3.89130300000001,-148.311086000000,5468.93304500000,1733.81029200000,2574.55417100000,139.918635585945,10785.2306925904,-1606.23622500000,-1187.00735500000,-1405.03808000000,-126601.478548000,-115926.811108000,-58371.9932849999];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
Bcol = rescale(X,'InputMin',colmin,'InputMax',colmax);
humanActivityData_scaled=array2table(Bcol);


% FIRST MACHINE LEARNING MODEL (stumble / other)

load('MLmodel1_stumble_other.mat')
a=MLmodel1_stumble_other.ClassificationSVM;

Predicted_Activities = predict(a,humanActivityData_scaled);

ones_pred_stumbles = ismember(Predicted_Activities,{'stumble'});
index_pred_stumbles=find(ones_pred_stumbles==1);

Stumbling_times = time_windows(index_pred_stumbles);
Total_stumbles = length(Stumbling_times)

if Total_stumbles>1
    
% SECOND MACHINE LEARNING MODEL (elevating / lowering)
time_windows=time_windows(index_pred_stumbles);
colmin_EL = [0.147055406955011,0.0735823070976745,0.193504691492651,2885.76753236437,12574.8127373229,198.991161091416,0.0841689466951984,6417.03871802137,-1121.32828900000,-584.915549000001,-979.374484000000,-51298.3537980003,-64111.3081809997,-33156.3386130001,332.461736838857,66990.9154769890,-43.3767200000004,120.830406000001,72.9080140000000,24004.0116550000,26443.0379070001,6568.48122000000,331.408731670135,62556.5894760241,-1294.55099100000,-1187.00735500000,-1405.03808000000,-98064.3089769997,-107108.756851000,-50894.7560250001];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
colmax_EL = [1.30766685775222,1.14228376514334,1.43816062996809,24800.1315488669,44146.3604207702,6200.94880362796,1.47087055471730,22621.4651951514,-81.3132469999988,290.259717999999,488.555687999999,37047.5915790000,30040.3431719999,29031.8279980000,1662.33435961503,131239.908321444,616.211520000000,1131.92450500000,1171.50731600000,103261.635069000,88065.2421269997,49884.3661880000,1660.42530273882,122445.013951042,-460.008589000001,-215.580263999999,-367.789735999998,-37593.8389000001,-25115.7986970000,-6145.27871800000];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
Bcol_EL = rescale(X,'InputMin',colmin_EL,'InputMax',colmax_EL);
humanActivityData_scaled_EL=array2table(Bcol_EL);
humanActivityData_scaled_stumbles=humanActivityData_scaled_EL(index_pred_stumbles,:);
humanActivityData_scaled_stumbles.Properties.VariableNames={'Bcol1','Bcol2','Bcol3','Bcol4','Bcol5','Bcol6','Bcol7','Bcol8','Bcol9','Bcol10','Bcol11','Bcol12','Bcol13','Bcol14','Bcol15','Bcol16','Bcol17','Bcol18','Bcol19','Bcol20','Bcol21','Bcol22','Bcol23','Bcol24','Bcol25','Bcol26','Bcol27','Bcol28','Bcol29','Bcol30','Bcol31','Bcol32','Bcol33','Bcol34','Bcol35','Bcol36','Bcol37','Bcol38','Bcol39','Bcol40','Bcol41','Bcol42','Bcol43','Bcol44','Bcol45','Bcol46','Bcol47','Bcol48','Bcol49','Bcol50','Bcol51','Bcol52','Bcol53','Bcol54','Bcol55','Bcol56','Bcol57','Bcol58','Bcol59','Bcol60','Bcol61','Bcol62','Bcol63','Bcol64','Bcol65','Bcol66','Bcol67','Bcol68','Bcol69','Bcol70','Bcol71','Bcol72','Bcol73','Bcol74','Bcol75','Bcol76','Bcol77','Bcol78','Bcol79','Bcol80','Bcol81','Bcol82','Bcol83','Bcol84','Bcol85','Bcol86','Bcol87','Bcol88','Bcol89','Bcol90','Bcol91','Bcol92','Bcol93','Bcol94','Bcol95','Bcol96','Bcol97','Bcol98','Bcol99','Bcol100','Bcol101','Bcol102','Bcol103','Bcol104','Bcol105','Bcol106','Bcol107','Bcol108','Bcol109','Bcol110','Bcol111','Bcol112','Bcol113','Bcol114','Bcol115','Bcol116','Bcol117','Bcol118','Bcol119','Bcol120','Bcol121','Bcol122','Bcol123','Bcol124','Bcol125','Bcol126','Bcol127','Bcol128','Bcol129','Bcol130','Bcol131','Bcol132','Bcol133','Bcol134','Bcol135','Bcol136','Bcol137','Bcol138','Bcol139','Bcol140','Bcol141','Bcol142','Bcol143','Bcol144','Bcol145','Bcol146','Bcol147','Bcol148','Bcol149','Bcol150','Bcol151','Bcol152','Bcol153','Bcol154','Bcol155','Bcol156','Bcol157','Bcol158','Bcol159','Bcol160','Bcol161','Bcol162','Bcol163','Bcol164','Bcol165','Bcol166','Bcol167','Bcol168','Bcol169','Bcol170','Bcol171','Bcol172','Bcol173','Bcol174','Bcol175','Bcol176','Bcol177','Bcol178','Bcol179','Bcol180','Bcol181','Bcol182','Bcol183','Bcol184','Bcol185','Bcol186','Bcol187','Bcol188','Bcol189','Bcol190','Bcol191','Bcol192','Bcol193','Bcol194','Bcol195','Bcol196','Bcol197','Bcol198','Bcol199','Bcol200','Bcol201','Bcol202','Bcol203','Bcol204','Bcol205','Bcol206'};

load('MLmodel2_elevating_lowering.mat')
a2=MLmodel2_elevating_lowering.ClassificationSVM;
Predicted_Strategy = predict(a2,humanActivityData_scaled_stumbles);

ones_pred_Elevating = ismember(Predicted_Strategy,{'Elevating'});
index_pred_Elevating=find(ones_pred_Elevating==1);
Elevating_stumbling_times = time_windows(index_pred_Elevating);
Total_Elevating_stumbles = length(Elevating_stumbling_times)

ones_pred_Lowering = ismember(Predicted_Strategy,{'Low'});
index_pred_Lowering=find(ones_pred_Lowering==1);
Lowering_stumbling_times = time_windows(index_pred_Lowering);
Total_Lowering_stumbles = length(Lowering_stumbling_times)


% HISTOGRAM
Stumbling = sort(datenum(Stumbling_times));
Elevating = sort(datenum(Elevating_stumbling_times));
Lowering = sort(datenum(Lowering_stumbling_times));

dates_kort = datetime(Stumbling,'ConvertFrom','datenum','Format','eeee, dd-MMM-yyyy');
first_date = dates_kort(1);
bins = discretize(dates_kort,'day');
dates_all = [first_date+days(0:bins(end)-1)]';
Cell = cellstr(char(dates_all));
C = categorical(bins,[1:bins(end)]',Cell);

hits=histcounts(bins);

dates_kort_E = datetime(Elevating,'ConvertFrom','datenum','Format','eeee, dd-MMM-yyyy');
emmpty_E = isempty(dates_kort_E);
if emmpty_E~=1
first_date_E = dates_kort_E(1);
bins_E = discretize(dates_kort_E,'day');
dates_all_E = [first_date_E+days(0:bins_E(end)-1)]';
Cell_E = cellstr(char(dates_all_E));
C_E = categorical(bins_E,[1:bins_E(end)]',Cell_E);
F_E=floor(Elevating(1));
hits_E=histcounts(bins_E);
else C_E = 0;
    F_E = 0; %NaN;
  	bins_E = [];
    hits_E = 0;
end

dates_kort_L = datetime(Lowering,'ConvertFrom','datenum','Format','eeee, dd-MMM-yyyy');
emmpty_L = isempty(dates_kort_L);
if emmpty_L~=1
first_date_L = dates_kort_L(1);
bins_L = discretize(dates_kort_L,'day');
dates_all_L = [first_date_L+days(0:bins_L(end)-1)]';
Cell_L = cellstr(char(dates_all_L));
C_L = categorical(bins_L,[1:bins_L(end)]',Cell_L);
F_L=floor(Lowering(1));
hits_L=histcounts(bins_L);
else C_L = 0;
    F_L=0; %0;  
    bins_L = [];
    hits_L = 0;
end

Uniq=sort(unique([unique(bins_E);unique(bins_L)]))';

hits = accumarray(bins(:),1);
hits_E = accumarray(bins_E(:),1);
hits_L = accumarray(bins_L(:),1);

if length(hits_E)~= length(hits)
    hits_E(max(Uniq))=0;
    hits_E=hits_E';
end
if length(hits_L)~= length(hits)
    hits_L(max(Uniq))=0;
    hits_L=hits_L';
end

difference=abs(F_E-F_L);

h = histogram(app.UIAxes,C)
hold(app.UIAxes, 'on')
if F_E<F_L
h2 = histogram(app.UIAxes,C_L)
elseif F_E>F_L
h1 = histogram(app.UIAxes,C_E)
else 
h1 = histogram(app.UIAxes,C_E)
end

hits_new_E=hits_E;
hits_new_L=hits_L;


Index=hits==0;
hits(Index)=NaN;
Index_E=hits_E==0;
hits_E(Index_E)=NaN;
Index_L=hits_L==0;
hits_L(Index_L)=NaN;


text(app.UIAxes,[1:bins(end)]', hits+2, string(hits))
if emmpty_E~=1
text(app.UIAxes,[1:max(Uniq)]', hits_E/2, string(hits_E)+" E")
end
if emmpty_L~=1
text(app.UIAxes,[1:max(Uniq)]', hits_L/2+hits_E, string(hits_L)+" L")
end

time_start = datetime(data.Var1(1),'ConvertFrom','datenum','Format','dd-MMM-yyyy HH:mm:ss');
time_end = datetime(data.Var1(end),'ConvertFrom','datenum','Format','dd-MMM-yyyy HH:mm:ss');
meettijd = time_end-time_start;
Stumbles_per_day = Total_stumbles/ceil(days(meettijd));


for i=1:length(Elevating_stumbling_times)
index_elevating(i)=find(Stumbling_times==Elevating_stumbling_times(i))
end

for i=1:length(Lowering_stumbling_times)
index_lowering(i)=find(Stumbling_times==Lowering_stumbling_times(i))
end

else
    time_start = datetime(data.Var1(1),'ConvertFrom','datenum','Format','dd-MMM-yyyy HH:mm:ss');
    time_end = datetime(data.Var1(end),'ConvertFrom','datenum','Format','dd-MMM-yyyy HH:mm:ss');
    meettijd = time_end-time_start;
    Stumbles_per_day = 0
end

if Total_stumbles>1
app.TextArea.Value{1} = ['Stumbles                      ' num2str(Total_stumbles)]
app.TextArea.Value{2} = [' -Elevating strategy      ' num2str(Total_Elevating_stumbles)]
app.TextArea.Value{3} = [' -Lowering strategy      ' num2str(Total_Lowering_stumbles) ]
app.TextArea.Value{4} = ['Stumbles per day         ' num2str(Stumbles_per_day) ]
app.TextArea.Value{5} = ['Duration                       ' char(meettijd) ]

app.TextArea.Value{6} = ' '

app.TextArea.Value{7} = 'Stumbling times'

for i=1:Total_stumbles
    if intersect(index_elevating,i)>0
app.TextArea.Value{i+7} = [datestr(Stumbling_times(i)) '    E']
    else 
        app.TextArea.Value{i+7} = [datestr(Stumbling_times(i)) '    L']
    end
end
else 
    app.TextArea.Value{1} = ['Stumbles                      ' num2str(Total_stumbles)]
    app.TextArea.Value{2} = ['Duration measurement         ' char(meettijd) ]
end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
cla(app.UIAxes) 
app.TextArea.Value = ['']
app.TextArea.Value {1}= ['1. Click on the ''Import & Calculate'' button.']
app.TextArea.Value {2}= [' ']
app.TextArea.Value {3}= ['2. Select ''All Files''.']
app.TextArea.Value {4}= [' ']
app.TextArea.Value {5}= ['3. Select the desired .cwa file and click on ''Open''.']
app.TextArea.Value {6}= [' ']
app.TextArea.Value {7}= ['4. Wait till the calculations are complete. This may take a few minutes.']


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImportCalculateButton = uibutton(app.UIFigure, 'push');
            app.ImportCalculateButton.ButtonPushedFcn = createCallbackFcn(app, @ImportCalculateButtonPushed, true);
            app.ImportCalculateButton.Icon = 'upload.png';
            app.ImportCalculateButton.Text = 'Import & Calculate';
            app.TextArea = uitextarea(app.UIFigure);
            app.StumblemeterLabel = uilabel(app.UIFigure);
            app.StumblemeterLabel.Text = 'Stumblemeter';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'TUD.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'logo_erasmusmc.png';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = 'information.png';
            app.HelpButton.Text = 'Help';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Day')
            ylabel(app.UIAxes, 'Stumbles')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stumblemeter_matlab_script

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end